classdef WaterTankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        RootGrid            matlab.ui.container.GridLayout
        MainTabGroup        matlab.ui.container.TabGroup
        Tab1                matlab.ui.container.Tab
        Tab1Grid            matlab.ui.container.GridLayout
        StartStatusLabel    matlab.ui.control.Label
        StartButton         matlab.ui.control.Button
        HypothesisTextArea  matlab.ui.control.TextArea
        HypothesisLabel     matlab.ui.control.Label
        TitleEditField      matlab.ui.control.EditField
        TitleLabel          matlab.ui.control.Label
        Tab2                matlab.ui.container.Tab
        Tab2Grid            matlab.ui.container.GridLayout
        Tab2StatusLabel     matlab.ui.control.Label
        SweepPanel          matlab.ui.container.Panel
        SweepGrid           matlab.ui.container.GridLayout
        RunSweepButton      matlab.ui.control.Button
        KdRangeEditField    matlab.ui.control.EditField
        KdRangeLabel        matlab.ui.control.Label
        KiRangeEditField    matlab.ui.control.EditField
        KiRangeLabel        matlab.ui.control.Label
        KpRangeEditField    matlab.ui.control.EditField
        KpRangeLabel        matlab.ui.control.Label
        SinglePanel         matlab.ui.container.Panel
        SingleGrid          matlab.ui.container.GridLayout
        RunSingleButton     matlab.ui.control.Button
        KdEditField         matlab.ui.control.NumericEditField
        KdLabel             matlab.ui.control.Label
        KiEditField         matlab.ui.control.NumericEditField
        KiLabel             matlab.ui.control.Label
        KpEditField         matlab.ui.control.NumericEditField
        KpLabel             matlab.ui.control.Label
        ModeDropDown        matlab.ui.control.DropDown
        Tab3                matlab.ui.container.Tab
        Tab3Grid            matlab.ui.container.GridLayout
        Tab3StatusLabel     matlab.ui.control.Label
        OpenMlflowButton    matlab.ui.control.Button
        SyncNotesButton     matlab.ui.control.Button
        DiscussionTextArea  matlab.ui.control.TextArea
        DiscussionLabel     matlab.ui.control.Label
        SummarizeButton     matlab.ui.control.Button
        SyncButton          matlab.ui.control.Button
        Tab4                matlab.ui.container.Tab
        Tab4Grid            matlab.ui.container.GridLayout
        HistoryTextArea     matlab.ui.control.TextArea
        HistoryTable        matlab.ui.control.Table
        HeaderLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        CurrentSessionId = ""
    end

    properties (Access = private)
        HistoryNotePaths = ""
    end

    methods (Access = private)
        function s = pickstr(~, tok)
            if isempty(tok)
                s = '';
            else
                s = tok{1};
            end
        end
        function refreshHistoryTable(app)
            notesDir = fullfile(fileparts(mfilename('fullpath')), 'notes');
            files = dir(fullfile(notesDir, '*.md'));
            n = numel(files);
            data = cell(n, 4);
            paths = cell(n, 1);
            for i = 1:n
                p = fullfile(notesDir, files(i).name);
                paths{i} = p;
                txt = fileread(p);
                sid = regexp(txt, 'session_id:\s*"([^"]+)"', 'tokens', 'once');
                st  = regexp(txt, 'status:\s*(\S+)', 'tokens', 'once');
                cr  = regexp(txt, 'created:\s*(\S+)', 'tokens', 'once');
                ti  = regexp(txt, '#\s*実験:[ \t]*([^\n]*)', 'tokens', 'once');
                data{i,1} = app.pickstr(cr);
                data{i,2} = app.pickstr(sid);
                data{i,3} = app.pickstr(ti);
                data{i,4} = app.pickstr(st);
            end
            app.HistoryTable.Data = data;
            app.HistoryNotePaths = paths;
        end
        function out = runManageSimstock(app, argsStr)
            scriptPath = fullfile(getenv('HOME'), 'Documents', 'MATLAB', 'manage_simstock.py');
            pythonExe = '/Library/Frameworks/Python.framework/Versions/3.13/bin/python3';
            [status, out] = system(sprintf('"%s" "%s" %s', pythonExe, scriptPath, argsStr));
            if status ~= 0
                error('manage_simstock.py failed (status=%d): %s', status, out);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.refreshHistoryTable();
            if evalin('base', 'exist(''session_id'',''var'')')
                app.CurrentSessionId = evalin('base', 'session_id');
                app.HeaderLabel.Text = sprintf('現在のセッション: %s', app.CurrentSessionId);
            end
        end

        % Selection change function: MainTabGroup
        function onTabChanged(app, event)
            if event.NewValue == app.Tab4
                app.refreshHistoryTable();
            end
        end

        % Button pushed function: StartButton
        function onStartExperiment(app, event)
            title = app.TitleEditField.Value;
            hyp = strjoin(app.HypothesisTextArea.Value, newline);
            if isempty(strtrim(title))
                uialert(app.UIFigure, 'タイトルを入力してください', '入力エラー');
                return;
            end
            noteFile = new_experiment_note(title, hyp);
            app.CurrentSessionId = evalin('base', 'session_id');
            app.HeaderLabel.Text = sprintf('現在のセッション: %s', app.CurrentSessionId);
            app.StartStatusLabel.Text = sprintf('作成: %s', noteFile);
            app.refreshHistoryTable();
        end

        % Value changed function: ModeDropDown
        function onModeChanged(app, event)
            isSweep = strcmp(app.ModeDropDown.Value, 'スイープ');
            app.SinglePanel.Visible = ~isSweep;
            app.SweepPanel.Visible = isSweep;
        end

        % Button pushed function: RunSingleButton
        function onRunSingle(app, event)
            app.Tab2StatusLabel.Text = '実行中...';
            drawnow;
            assignin('base', 'Kp_Level', app.KpEditField.Value);
            assignin('base', 'Ki_Level', app.KiEditField.Value);
            assignin('base', 'Kd_Level', app.KdEditField.Value);
            try
                run('WaterTankLevelControlDemo_mlflow_run.m');
                app.Tab2StatusLabel.Text = '完了: 単発実行がMLflowに登録されました';
            catch ME
                app.Tab2StatusLabel.Text = ['エラー: ' ME.message];
            end
        end

        % Button pushed function: RunSweepButton
        function onRunSweep(app, event)
            app.Tab2StatusLabel.Text = '実行中... (数分かかる場合があります)';
            drawnow;
            try
                Kp_vals = str2num(app.KpRangeEditField.Value); %#ok<ST2NM>
                Ki_vals = str2num(app.KiRangeEditField.Value); %#ok<ST2NM>
                Kd_vals = str2num(app.KdRangeEditField.Value); %#ok<ST2NM>
                run_parameter_sweep(Kp_vals, Ki_vals, Kd_vals);
                app.Tab2StatusLabel.Text = '完了: スイープ結果がMLflowに登録されました';
            catch ME
                app.Tab2StatusLabel.Text = ['エラー: ' ME.message];
            end
        end

        % Button pushed function: SyncButton
        function onSync(app, event)
            app.Tab3StatusLabel.Text = '同期中...';
            drawnow;
            app.runManageSimstock('sync');
            app.Tab3StatusLabel.Text = '同期完了';
        end

        % Button pushed function: SummarizeButton
        function onSummarize(app, event)
            if isempty(app.CurrentSessionId)
                uialert(app.UIFigure, '先に「実験開始」タブでセッションを開始してください', 'セッション未設定');
                return;
            end
            app.runManageSimstock(['summarize ' app.CurrentSessionId]);
            app.Tab3StatusLabel.Text = '結果表を挿入しました';
        end

        % Button pushed function: SyncNotesButton
        function onSyncNotes(app, event)
            if isempty(app.CurrentSessionId)
                uialert(app.UIFigure, '先に「実験開始」タブでセッションを開始してください', 'セッション未設定');
                return;
            end
            notesDir = fullfile(fileparts(mfilename('fullpath')), 'notes');
            matches = dir(fullfile(notesDir, [app.CurrentSessionId '_*.md']));
            if isempty(matches)
                uialert(app.UIFigure, 'ノートファイルが見つかりません', 'エラー');
                return;
            end
            noteFile = fullfile(notesDir, matches(1).name);
            txt = fileread(noteFile);
            discussion = strjoin(app.DiscussionTextArea.Value, newline);
            newTxt = regexprep(txt, '## 考察・結論.*$', ['## 考察・結論' newline discussion], 'once', 'dotall');
            fid = fopen(noteFile, 'w');
            fwrite(fid, newTxt);
            fclose(fid);
            app.runManageSimstock('sync-notes');
            app.Tab3StatusLabel.Text = '考察をMLflowへ反映しました';
        end

        % Button pushed function: OpenMlflowButton
        function onOpenMlflow(app, event)
            web('http://127.0.0.1:5001', '-browser');
        end

        % Cell selection callback: HistoryTable
        function onHistorySelect(app, event)
            if isempty(event.Indices)
                return;
            end
            row = event.Indices(1);
            if row >= 1 && row <= numel(app.HistoryNotePaths)
                app.HistoryTextArea.Value = strsplit(fileread(app.HistoryNotePaths{row}), newline);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'WaterTankApp';
            app.RootGrid = uigridlayout(app.UIFigure);
            app.HeaderLabel = uilabel(app.RootGrid);
            app.HeaderLabel.Text = '現在のセッション: なし';
            app.MainTabGroup = uitabgroup(app.RootGrid);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @onTabChanged, true);
            app.Tab1 = uitab(app.MainTabGroup);
            app.Tab1.Title = '実験開始';
            app.Tab1Grid = uigridlayout(app.Tab1);
            app.TitleLabel = uilabel(app.Tab1Grid);
            app.TitleLabel.Text = 'タイトル:';
            app.TitleEditField = uieditfield(app.Tab1Grid, 'text');
            app.HypothesisLabel = uilabel(app.Tab1Grid);
            app.HypothesisLabel.Text = '目的・仮説:';
            app.HypothesisTextArea = uitextarea(app.Tab1Grid);
            app.StartButton = uibutton(app.Tab1Grid, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @onStartExperiment, true);
            app.StartButton.Text = '実験開始';
            app.StartStatusLabel = uilabel(app.Tab1Grid);
            app.StartStatusLabel.Text = '';
            app.Tab2 = uitab(app.MainTabGroup);
            app.Tab2.Title = 'シミュレーション実行';
            app.Tab2Grid = uigridlayout(app.Tab2);
            app.ModeDropDown = uidropdown(app.Tab2Grid);
            app.ModeDropDown.Items = {'単発', 'スイープ'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @onModeChanged, true);
            app.ModeDropDown.Value = '単発';
            app.SinglePanel = uipanel(app.Tab2Grid);
            app.SinglePanel.Title = '単発実行';
            app.SingleGrid = uigridlayout(app.SinglePanel);
            app.KpLabel = uilabel(app.SingleGrid);
            app.KpLabel.Text = 'Kp:';
            app.KpEditField = uieditfield(app.SingleGrid, 'numeric');
            app.KpEditField.Value = 4;
            app.KiLabel = uilabel(app.SingleGrid);
            app.KiLabel.Text = 'Ki:';
            app.KiEditField = uieditfield(app.SingleGrid, 'numeric');
            app.KiEditField.Value = 0.4;
            app.KdLabel = uilabel(app.SingleGrid);
            app.KdLabel.Text = 'Kd:';
            app.KdEditField = uieditfield(app.SingleGrid, 'numeric');
            app.RunSingleButton = uibutton(app.SingleGrid, 'push');
            app.RunSingleButton.ButtonPushedFcn = createCallbackFcn(app, @onRunSingle, true);
            app.RunSingleButton.Text = '実行';
            app.SweepPanel = uipanel(app.Tab2Grid);
            app.SweepPanel.Title = 'パラメータスイープ';
            app.SweepPanel.Visible = 'off';
            app.SweepGrid = uigridlayout(app.SweepPanel);
            app.KpRangeLabel = uilabel(app.SweepGrid);
            app.KpRangeLabel.Text = 'Kp範囲:';
            app.KpRangeEditField = uieditfield(app.SweepGrid, 'text');
            app.KpRangeEditField.Placeholder = '例: 2:6';
            app.KpRangeEditField.Value = '2:6';
            app.KiRangeLabel = uilabel(app.SweepGrid);
            app.KiRangeLabel.Text = 'Ki範囲:';
            app.KiRangeEditField = uieditfield(app.SweepGrid, 'text');
            app.KiRangeEditField.Placeholder = '例: 0.2:0.1:0.6';
            app.KiRangeEditField.Value = '0.2:0.1:0.6';
            app.KdRangeLabel = uilabel(app.SweepGrid);
            app.KdRangeLabel.Text = 'Kd範囲:';
            app.KdRangeEditField = uieditfield(app.SweepGrid, 'text');
            app.KdRangeEditField.Placeholder = '例: 0 または 0.2:0.1:0.6';
            app.KdRangeEditField.Value = '0';
            app.RunSweepButton = uibutton(app.SweepGrid, 'push');
            app.RunSweepButton.ButtonPushedFcn = createCallbackFcn(app, @onRunSweep, true);
            app.RunSweepButton.Text = '実行';
            app.Tab2StatusLabel = uilabel(app.Tab2Grid);
            app.Tab2StatusLabel.Text = '';
            app.Tab3 = uitab(app.MainTabGroup);
            app.Tab3.Title = '結果・考察';
            app.Tab3Grid = uigridlayout(app.Tab3);
            app.SyncButton = uibutton(app.Tab3Grid, 'push');
            app.SyncButton.ButtonPushedFcn = createCallbackFcn(app, @onSync, true);
            app.SyncButton.Text = 'MLflowへ同期';
            app.SummarizeButton = uibutton(app.Tab3Grid, 'push');
            app.SummarizeButton.ButtonPushedFcn = createCallbackFcn(app, @onSummarize, true);
            app.SummarizeButton.Text = '結果表をノートに挿入';
            app.DiscussionLabel = uilabel(app.Tab3Grid);
            app.DiscussionLabel.Text = '考察・結論:';
            app.DiscussionTextArea = uitextarea(app.Tab3Grid);
            app.SyncNotesButton = uibutton(app.Tab3Grid, 'push');
            app.SyncNotesButton.ButtonPushedFcn = createCallbackFcn(app, @onSyncNotes, true);
            app.SyncNotesButton.Text = 'MLflowに反映';
            app.OpenMlflowButton = uibutton(app.Tab3Grid, 'push');
            app.OpenMlflowButton.ButtonPushedFcn = createCallbackFcn(app, @onOpenMlflow, true);
            app.OpenMlflowButton.Text = 'MLflowを開く';
            app.Tab3StatusLabel = uilabel(app.Tab3Grid);
            app.Tab3StatusLabel.Text = '';
            app.Tab4 = uitab(app.MainTabGroup);
            app.Tab4.Title = '実験履歴';
            app.Tab4Grid = uigridlayout(app.Tab4);
            app.HistoryTable = uitable(app.Tab4Grid);
            app.HistoryTable.ColumnName = {'作成日'; 'session_id'; 'タイトル'; 'ステータス'};
            app.HistoryTable.ColumnEditable = [false false false false];
            app.HistoryTable.CellSelectionCallback = createCallbackFcn(app, @onHistorySelect, true);
            app.HistoryTextArea = uitextarea(app.Tab4Grid);
            app.HistoryTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaterTankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end